classdef measurementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasurementApp                 matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        StatusTable                    matlab.ui.control.Table
        Console                        matlab.ui.control.TextArea
        IncrementSizeEditFieldLabel    matlab.ui.control.Label
        IncrementSizeEditField         matlab.ui.control.NumericEditField
        PauseButton                    matlab.ui.control.StateButton
        IdleLampLabel                  matlab.ui.control.Label
        IdleLamp                       matlab.ui.control.Lamp
        InMotionLampLabel              matlab.ui.control.Label
        InMotionLamp                   matlab.ui.control.Lamp
        MeasuringLampLabel             matlab.ui.control.Label
        MeasuringLamp                  matlab.ui.control.Lamp
        HornImage                      matlab.ui.control.UIAxes
        ProgressBar                    matlab.ui.container.Panel
        CenterFrequencyEditFieldLabel  matlab.ui.control.Label
        CenterFrequencyValue           matlab.ui.control.NumericEditField
        CenterFrequencyUnits           matlab.ui.control.DropDown
        FaultLampLabel                 matlab.ui.control.Label
        FaultLamp                      matlab.ui.control.Lamp
        WIPResetPositionButton         matlab.ui.control.Button
        CurrentPositionLabel           matlab.ui.control.Label
        CurrentAngleGuage              matlab.ui.control.SemicircularGauge
        IncrementSizeConfirmButton     matlab.ui.control.Button
        CenterFreqConfirmButton        matlab.ui.control.Button
        USRPsPanel                     matlab.ui.container.Panel
        N310DisconnectedLabel          matlab.ui.control.Label
        N310Lamp                       matlab.ui.control.Lamp
        N210DisconnectedLampLabel      matlab.ui.control.Label
        N210Lamp                       matlab.ui.control.Lamp
        CamFeed                        matlab.ui.container.Panel
    end

    
    properties (Access = public)
    
        consoleObj
        MI4190Obj
        wantToStop = false;
        imageObj;
        imageHandleObj;
        axObj;
        phObj;
        thObj;
        progressBarTextObj;
        itrObj;
        incrementSizeObj = 0;
        centerFreqObj = 0;
        centerFreqUnitsObj = 'Select units...';
        
    end

    methods (Access = public)
        
        function writeConsoleLine(app,lineIn)
            app.Console.Value = [lineIn;app.Console.Value];
        end
        
        function rotateHornImage(app,rotation)
            app.imageHandleObj.CData = imrotate(app.imageObj,rotation);
        end
        
        function loadImage(app)
            im = imread('SSHorn.png');
            imh = imshow(im,'Parent',app.HornImage);
            app.imageHandleObj = imh;
            app.imageObj = im;
        end
        
        function createProgressBar(app)
            ax = axes(app.ProgressBar,'Position',[.1 .4 .8 .1],'box','on','xtick',[],'ytick',[],...
    'color',[0.65 0.65 0.65],'xlim',[0,1],'ylim',[0,1]);
            title(ax,'Initializing. . .')
            % Create empty patch that will be updated
            ph = patch(ax,[0 0 0 0],[0 0 1 1],[0 1 0]);
            % Create the percent-complete text that will be updated
            th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            
            app.axObj = ax;
            app.phObj = ph;
            app.thObj = th;
             
        end
        
        function createCamFeed(app)
            camAx = axes(app.CamFeed,'Units', 'normalized', 'Position', [0 0 1 1]);
            title(camAx,'Anechoic Cam Feed');
            cam = ipcam('http://192.168.10.7:80/streaming/channels/1/httppreview', 'admin', 'USRPN310!');
            frame = snapshot(cam);
            im = image(camAx, zeros(size(frame), 'uint8'));   
            axis(camAx, 'image');
            
            preview(cam,im);
            setappdata(app.MeasurementApp, 'cam', cam);
            
            %app.CamFeed.Position = app.CamFeed.Position .* [1 1 2 1];
            
        end
        
        function updateProgressBar(app,progress,lineIn)
                        
            app.phObj.XData = [0 progress progress 0];
            app.thObj.String = sprintf('%.0f%%',round(progress*100));
           
            title(app.axObj,lineIn);
        end
        
        function saveLogToFile(app) %incomplete, don't know how to open file browser to choose save location via button press.
            for i = length(app.Console.Value)
                if isempty(cell2mat(app.Console.Value(i)))
                    app.Console.Value(i) = {' '};
                end
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadImage(app); 
            createProgressBar(app);
            %createCamFeed(app);
                        
            app.consoleObj.line1 = 'Console Started';
            app.Console.Value = {app.consoleObj.line1};
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            writeConsoleLine(app,'Start Button pressed');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            uiresume(gcbf);
            MeasurementAppCloseRequest(app);
        end

        % Close request function: MeasurementApp
        function MeasurementAppCloseRequest(app, event)
            uiresume(gcbf);
            app.writeConsoleLine(sprintf('[%s] Stopping Measurements. . .',datestr(now,'HH:MM:SS.FFF')));
            fprintf('[%s] Stopping Measurements. . .\n',datestr(now,'HH:MM:SS.FFF'))
            
            app.wantToStop = true;
                        
            if (~isempty(app.MI4190Obj))
                if (isvalid(app.MI4190Obj))
                    fclose(app.MI4190Obj);
                    delete(app.MI4190Obj);
                    clear app.MI4190Obj;
                end
            elseif (isempty(app.MI4190Obj))
                delete(app);
            end
      
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            
            if (value == 1)
                app.progressBarTextObj = app.axObj.Title.String;
                app.updateProgressBar(app.phObj.XData(2),'System Paused. . .');
                app.PauseButton.Text = 'Resume';
                app.writeConsoleLine(sprintf('[%s] System Paused',datestr(now,'HH:MM:SS.FFF')));
                uiwait(gcbf);
            end
            
            if (value == 0)
                app.updateProgressBar(app.phObj.XData(2),app.progressBarTextObj);
                app.PauseButton.Text = 'Pause';
                app.writeConsoleLine(sprintf('[%s] System Resumed.',datestr(now,'HH:MM:SS.FFF')));
                uiresume(gcbf);
            end
           
        end

        % Callback function
        function ResetPositionButtonValueChanged(app, event)
            value = app.WIPResetPositionButton.Value;
            
            if (value == 1)
                app.writeConsoleLine('Reset Position Requested. . .');
            else 
                app.writeConsoleLine('WAIT');
            end
        end

        % Button pushed function: WIPResetPositionButton
        function WIPResetPositionButtonPushed(app, event)
            %reset position back to -90
            %reset increment counter and next position, basically entire
            %loop
            %verifyIfInPosition(app.MI4190Obj,-90,0.6,app.phObj.XData(2),app,app.StatusTable.Data(4,1),app.IncrementSizeEditField.Value);
            %app.itrObj = 0;
        end

        % Button pushed function: IncrementSizeConfirmButton
        function IncrementSizeConfirmButtonPushed(app, event)
            app.incrementSizeObj = app.IncrementSizeEditField.Value;
        end

        % Button pushed function: CenterFreqConfirmButton
        function CenterFreqConfirmButtonPushed(app, event)
            app.centerFreqObj = app.CenterFrequencyValue.Value;
            app.centerFreqUnitsObj = app.CenterFrequencyUnits.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasurementApp = uifigure('Visible', 'off');
            app.MeasurementApp.Name = 'Measurement App';
            app.MeasurementApp.CloseRequestFcn = createCallbackFcn(app, @MeasurementAppCloseRequest, true);
            app.FileMenu = uimenu(app.MeasurementApp);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.StopButton = uibutton(app.MeasurementApp, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tooltip = {'Stops the entire system and closes the app.'};
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.MeasurementApp, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusTable = uitable(app.MeasurementApp);
            app.StatusTable.ColumnName = {'Status Table'};
            app.StatusTable.RowName = {'Current Position: '; 'Next Position: '; 'Current Velocity: '; 'Angles Remaining: '};
            app.Console = uitextarea(app.MeasurementApp);
            app.Console.Editable = 'off';
            app.Console.Tooltip = {'Console describing each step of the measurement process and indicating any errors.'};
            app.IncrementSizeEditFieldLabel = uilabel(app.MeasurementApp);
            app.IncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.IncrementSizeEditField = uieditfield(app.MeasurementApp, 'numeric');
            app.IncrementSizeEditField.Limits = [0 180];
            app.IncrementSizeEditField.Tooltip = {'Input the desired Increment Size for the Position Controller to increment its position after each measurement. Taken in degrees.'};
            app.PauseButton = uibutton(app.MeasurementApp, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Tooltip = {'Pauses the entire system until the user presses ''Resume'''};
            app.PauseButton.Text = 'Pause';
            app.IdleLampLabel = uilabel(app.MeasurementApp);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.MeasurementApp);
            app.IdleLamp.Tooltip = {'Lamp will be lit green to indicate MI-4190 Position Controller currently idle.'};
            app.InMotionLampLabel = uilabel(app.MeasurementApp);
            app.InMotionLampLabel.Text = 'In Motion';
            app.InMotionLamp = uilamp(app.MeasurementApp);
            app.InMotionLamp.Tooltip = {'Lamp will be lit green to indicate MI-4190 Position Controller currently in motion.'};
            app.MeasuringLampLabel = uilabel(app.MeasurementApp);
            app.MeasuringLampLabel.Text = 'Measuring';
            app.MeasuringLamp = uilamp(app.MeasurementApp);
            app.MeasuringLamp.Tooltip = {'Lamp will be lit green to indicate USRP N310 taking measurement.'};
            app.HornImage = uiaxes(app.MeasurementApp);
            title(app.HornImage, '')
            xlabel(app.HornImage, '')
            ylabel(app.HornImage, '')
            app.ProgressBar = uipanel(app.MeasurementApp);
            app.ProgressBar.Tooltip = {'Progress Bar to indicate current progress during a measurement cycle.'};
            app.CenterFrequencyEditFieldLabel = uilabel(app.MeasurementApp);
            app.CenterFrequencyEditFieldLabel.Text = 'Center Frequency';
            app.CenterFrequencyValue = uieditfield(app.MeasurementApp, 'numeric');
            app.CenterFrequencyValue.Limits = [0 180];
            app.CenterFrequencyValue.ValueDisplayFormat = '%11.5f';
            app.CenterFrequencyValue.Tooltip = {'Input the desired Center Frequency for the VNA to stream. Then select the desired units on the right.'};
            app.CenterFrequencyUnits = uidropdown(app.MeasurementApp);
            app.CenterFrequencyUnits.Items = {'Select units...', 'Hz', 'kHz', 'MHz', 'GHz'};
            app.CenterFrequencyUnits.Value = 'Select units...';
            app.FaultLampLabel = uilabel(app.MeasurementApp);
            app.FaultLampLabel.Text = 'Fault';
            app.FaultLamp = uilamp(app.MeasurementApp);
            app.WIPResetPositionButton = uibutton(app.MeasurementApp, 'push');
            app.WIPResetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @WIPResetPositionButtonPushed, true);
            app.WIPResetPositionButton.Text = '[WIP] Reset Position';
            app.CurrentPositionLabel = uilabel(app.MeasurementApp);
            app.CurrentPositionLabel.Text = 'Current Position';
            app.CurrentAngleGuage = uigauge(app.MeasurementApp, 'semicircular');
            app.CurrentAngleGuage.Limits = [-90 90];
            app.CurrentAngleGuage.Tooltip = {'A guage to visualize the current position of the Axis (AZ)'};
            app.IncrementSizeConfirmButton = uibutton(app.MeasurementApp, 'push');
            app.IncrementSizeConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementSizeConfirmButtonPushed, true);
            app.IncrementSizeConfirmButton.Text = 'Confirm';
            app.CenterFreqConfirmButton = uibutton(app.MeasurementApp, 'push');
            app.CenterFreqConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @CenterFreqConfirmButtonPushed, true);
            app.CenterFreqConfirmButton.Text = 'Confirm';
            app.USRPsPanel = uipanel(app.MeasurementApp);
            app.USRPsPanel.Title = 'USRPs';
            app.N310DisconnectedLabel = uilabel(app.USRPsPanel);
            app.N310DisconnectedLabel.Text = 'N310 | Disconnected';
            app.N310Lamp = uilamp(app.USRPsPanel);
            app.N310Lamp.Tooltip = {'Lamp will be lit green to indicate USRP N310 is connected, and red when disconnected '};
            app.N210DisconnectedLampLabel = uilabel(app.USRPsPanel);
            app.N210DisconnectedLampLabel.Text = 'N210 | Disconnected';
            app.N210Lamp = uilamp(app.USRPsPanel);
            app.N210Lamp.Tooltip = {'Lamp will be lit green to indicate USRP N210 is connected, and red when disconnected '};
            app.CamFeed = uipanel(app.MeasurementApp);
            app.CamFeed.Tooltip = {'Progress Bar to indicate current progress during a measurement cycle.'};
            % Show the figure after all components are created
            app.MeasurementApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = measurementApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasurementApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasurementApp)
        end
    end
end